classdef ecog_artifact_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InfoLabel                     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SMSLTab                       matlab.ui.container.Tab
        rime_errorTextArea            matlab.ui.control.TextArea
        rime_errorTextAreaLabel       matlab.ui.control.Label
        unusable_trialTextArea        matlab.ui.control.TextArea
        unusable_trialTextAreaLabel   matlab.ui.control.Label
        stop_responseTextArea         matlab.ui.control.TextArea
        stop_responseTextAreaLabel    matlab.ui.control.Label
        wordTextArea                  matlab.ui.control.TextArea
        wordTextAreaLabel             matlab.ui.control.Label
        onset_errorTextArea           matlab.ui.control.TextArea
        onset_errorTextAreaLabel      matlab.ui.control.Label
        is_stoptrialTextArea          matlab.ui.control.TextArea
        is_stoptrialTextAreaLabel     matlab.ui.control.Label
        tripletTab                    matlab.ui.container.Tab
        rdconsonantTextArea           matlab.ui.control.TextArea
        rdconsonantTextAreaLabel      matlab.ui.control.Label
        rdvowelTextArea               matlab.ui.control.TextArea
        rdvowelTextAreaLabel          matlab.ui.control.Label
        ndconsonantTextArea           matlab.ui.control.TextArea
        stconsonantLabel              matlab.ui.control.Label
        ndvowelTextArea               matlab.ui.control.TextArea
        stvowelLabel                  matlab.ui.control.Label
        stconsonantTextArea           matlab.ui.control.TextArea
        stconsonantTextAreaLabel      matlab.ui.control.Label
        stvowelTextArea               matlab.ui.control.TextArea
        stvowelTextAreaLabel          matlab.ui.control.Label
        AccuracyLabel                 matlab.ui.control.Label
        missing_timing_dataTextArea   matlab.ui.control.TextArea
        missing_timing_dataTextAreaLabel  matlab.ui.control.Label
        wordTextArea_2                matlab.ui.control.TextArea
        wordTextArea_2Label           matlab.ui.control.Label
        classificationEditField       matlab.ui.control.EditField
        classificationEditFieldLabel  matlab.ui.control.Label
        finishedtrialButton           matlab.ui.control.Button
        commentsEditField             matlab.ui.control.EditField
        commentsEditFieldLabel        matlab.ui.control.Label
        questionableButton            matlab.ui.control.Button
        artifactualButton             matlab.ui.control.Button
        cleanButton                   matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % goes along with FreqBands_for_plotting.m
    % data needs to be prepared before running the GUI (frequencies calculated to be graphed in a spectrogram)
    % subjects that data is prepared for: sub-DM1005, DBS3001
    
    properties (Access = private)
        TASK;
        SUBJECT;
        SESSION; % only for triplet subjects
        TRIAL; % current trial number, taking session into account
        ELECTRODE;
        valid_triplet = {'DBS3001'};
        valid_SMSL = {'sub-DM1005'};
        valid_sub = {'DBS3001','sub-DM1005'};

        PATH_DATASET;
        PATH_SUB;
        PATH_PREPROC_DATA;
        PATH_FT;
        PATH_SYNC;
        PATH_ANNOT;
        
        FT_file
        FT_raw
        timings_file
        stimulus_file
        electrode_file
        accuracy_file
        channels_file

        ecog_plot
        x_axis
        y_axis

        FT_filename
        raw_filename
        FilePath

        filename % name of the results file

        count % current image number
        trialNo % current trial number, not caring about session
        allTrials % contains the names of all files
        totalTrials % number of total trials
        results % table of results
        tempResult % variable that stores the temporary result
        session_list % array that contains the amount of trials in every session

        SaveChangesApp % dialogue box to ask if changes need to be saved
    end
    
    methods (Access = private)
        function closeApp(app, save) 
            % to excecute before the app closes
            disp('closeApp');

            % return final results table
            %app.results
            %writetable(app.annot, [app.FilePath filesep app.filename], 'filetype','text','Delimiter','\t');
            if save
                location = app.FilePath;
                bml_annot_write_tsv(app,app.results,app.filename,location);
            end

            delete(app);
        end
        
        function hasBeen = hasBeenRun(app)
            % checks if there is a file that already has been generated
            % that its supposed to edit instead of creating a new file

            % checking if the directory contains the file
            filePathArray = string({dir(app.FilePath).name});
            %filePathArray = regexp(ls(app.FilePath),'\w*.tsv','match');

            if ismember(app.filename, filePathArray)
                hasBeen = true;
                %disp('hasBeen = true');
            else
                hasBeen = false;
                %disp('hasBeen = false');
            end 
        end
        
        function updateGraphandCount(app)
            app.count = app.count + 1;

            switch app.TASK
                case 'triplet'
                    if app.TRIAL == app.session_list(app.SESSION)
                        app.TRIAL = 1;
                        app.trialNo = app.trialNo + 1;
                        app.SESSION = app.SESSION + 1;
        
                        if app.session_list(app.SESSION) == 0
                            app.SESSION = app.SESSION + 1;
                        end
                    else
                        app.trialNo = app.trialNo + 1;
                        app.TRIAL = app.TRIAL + 1;
                    end
                case 'SMSL'
                    app.trialNo = app.trialNo + 1;
                    app.TRIAL = app.TRIAL + 1;
            end

            dispInfo(app);
        
            % update image
            plot_ecog(app);
        end
        
        function bml_annot_write_tsv(app,annot,filename,location,varargin)
            % BML_ANNOT_WRITE writes an annotation table
            %
            % Use as
            %   bml_annot_write(annot,'annot/MyAnnot.tsv')
            %
            % annot - annotation table to write
            % filename - char or string indicating file to load
            % varargin - further arguments for writetable
            
            fprintf('bml_annot_table');
            annot = bml_annot_table(app,annot,[],inputname(1)); % problem occurs here
            
            if ~exist('filename','var')
              if isempty(annot.Properties.Description)
                error("filename required");
              else
                filename = fullfile(pwd,annot.Properties.Description,'.tsv');
                fprintf("writing annot table in %s\n",filename);
              end
            end
            
            annot = bml_annot_rename(app,annot,'starts','onset');
            %annot = bml_annot_rename(app,annot,{'starts','onset'});
            annot.id=[];
            annot.ends=[];
            
            if ~ismember('delimiter',varargin)
              varargin = [varargin, {'delimiter','\t'}];
            end
            
            writetable(annot,[location filesep filename],varargin{:},'FileType','text');
        end

        function annot = bml_annot_read_tsv(app,filename,varargin)
            % BML_ANNOT_READ_TSV reads tab delimited vector BIDS annotation table
            %
            % Use as
            %   annot = bml_annot_read_tsv('annot/my_annot_table.tsv')
            %
            % filename - char or string indicating file to load
            % varargin - further arguments for readtable
            %
            % returns an annotation table
            
            if ~ismember('delimiter',varargin)
              varargin = [varargin, {'delimiter','\t'}];
            end
            if ~ismember('FileType',varargin)
              varargin = [varargin, {'FileType','text'}];
            end
            if ~ismember('TreatAsEmpty',varargin)
              varargin = [varargin, {'TreatAsEmpty',{'n/a'}}];
            end

            % if the file contains the classification column, it will set the classification and comments columns to strings
            opts = detectImportOptions(filename,'FileType','text');
            if ismember('classification',opts.VariableNames)
                opts = setvartype(opts,{'classification','comments'},'string');
                opts = setvaropts(opts,'TreatAsMissing','n/a');
                opts = setvaropts(opts,{'classification','comments'},'FillValue','');
                opts = setvaropts(opts,{'onset','duration','trial'},'FillValue',0);
                %allOptions = [varargin, opts.VariableOptions]; 
                %opts = setvaropts(opts,varargin{:});
                %annot = readtable(filename,opts,varargin{:});
                annot = readtable(filename,opts);
                %annot = readtable(filename,allOptions{:});
            else
                annot = readtable(filename,varargin{:});
            end

            [~,name,~]=fileparts(filename);
            is_onset = ismember('onset',annot.Properties.VariableNames);
            is_duration = ismember('duration',annot.Properties.VariableNames);
            if is_onset && is_duration
                annot = bml_annot_rename(app,annot,'onset','starts');
                annot.ends = annot.starts + annot.duration;
                annot.id = (1:height(annot))';
                annot = bml_annot_table(app,annot,name);
            end
        end

        function renamed = bml_annot_rename(app,annot,varargin)
            % BML_ANNOT_RENAME changes the names of columns
            %
            % Use as
            %    renamed = bml_annot_rename(annot, 'old_col1', 'new_col1', 'old_col2', 'new_col2',...)
            
            %annot = bml_annot_table(annot);
            
            if mod(numel(varargin),2) ~= 0
              error('matching old and new column names required');
            end
            
            for i=1:(numel(varargin)/2)
              old_var = varargin((i-1)*2+1);
              new_var = varargin(i*2);
              if ~ismember(old_var,annot.Properties.VariableNames)
                error('variable %s not present in annotation table', old_var)
              else
                idx = find(ismember(annot.Properties.VariableNames,old_var),1);
                annot.Properties.VariableNames(idx) = new_var;
              end
            end
            
            renamed = annot;
        end

        function annot = bml_annot_table(app,x,description,x_var_name)
            % BML_ANNOT_TABLE transforms a table into an annotations table [internal]
            %
            % Use as
            %   annot=bml_annot_table(x)
            %   annot=bml_annot_table(x, description)
            %   annot=bml_annot_table(x, [], x_var_name)
            %
            % x - table: should contains 'starts' and 'ends' variables
            % description - string: description of the annotation table
            % x_var_name - string: name of the variable used for the annot table, as
            %     returned by inputname(). Usefull for anidated calls.
            %
            % returns a table with variables 'id', 'starts', 'ends' and any additonal
            % variable present in x. Issues a error on inconsistent input. 
            
            if isempty(x) 
              x=table();
            end
            %fprintf('line 211 starts: %d\n', x.starts(1));
            
            if isnumeric(x)
              if size(x,1) < size(x,2)
                x = x';
              end
              x = table(x);
            end
            if iscell(x); x=cell2table(x); end
            if isstruct(x); x=struct2table(x); end
            %fprintf('line 222 starts: %d\n', x.starts(1));
            
            if ~exist('description','var') || isempty(description)
                disp(x);
              if isempty(x.Properties.Description)
                if exist('x_var_name','var')
                  description=x_var_name;
                else
                  description=inputname(1);
                end
              else
                description=x.Properties.Description;
              end
            end
            %fprintf('line 236 starts: %d\n', x.starts(1));
            
            if iscellstr(description) 
              if numel(description)==1
                description = description{1};
              else
                error('The Description property must be a character vector.');
              end
            end
            %fprintf('line 245 starts: %d\n', x.starts(1));
            
            if isempty(x)
              annot=table();
              annot.Properties.Description = description;
              return
            end
            %fprintf('line 252 starts: %d\n', x.starts(1));
            
            if ~ismember('starts',x.Properties.VariableNames)
              if width(x)<=2 
                x.Properties.VariableNames(1)={'starts'};
              else
                error('x should have variable ''starts''');
              end
            end
            %fprintf('line 261 starts: %d\n', x.starts(1));
            if iscell(x.starts)
                x.starts=cell2mat(x.starts);
            end
            %fprintf('line 265 starts: %d\n', x.starts(1));
            % if(any(ismissing(x.starts)))
            %   warning("starts contains nans");
            % end
            
            if ~ismember('ends',x.Properties.VariableNames)
              if width(x)==1 
                x.ends = x.starts;
              elseif width(x)==2 
                x.Properties.VariableNames(2)={'ends'};
              else
                error('x should have variable ''ends''');
              end  
            end
            %fprintf('line 279 starts: %d\n', x.starts(1));
            if iscell(x.ends)
                x.ends=cell2mat(x.ends);
            end
            %fprintf('line 283 starts: %d\n', x.starts(1));
            
            % problem occurs here
            if ~ismember('id',x.Properties.VariableNames)
              if ~ismember(0,x.starts);
                x = sortrows(x,'starts');
              end
              x = [table(transpose(1:height(x)),'VariableNames',{'id'}), x];
            else
              if iscell(x.id)
                x.id=cell2mat(x.id);
              end
              if length(unique(x.id)) < height(x)
                error('inconsistent id variable')
              else
                x = sortrows(x,'id');
              end
            end
            %fprintf('line 301 starts: %d\n', x.starts(1));
            
            if ismember('duration',x.Properties.VariableNames)
              x.duration = [];
            end
            x = [x, table(x.ends - x.starts,'VariableNames',{'duration'})];
            %fprintf('line 307 starts: %d\n', x.starts(1));
            
            annot = bml_annot_reorder_vars(app,x, {'id','starts','ends','duration'});
            annot.Properties.Description = description;
            %fprintf('line 311 starts: %d\n', x.starts(1));
            %disp(app.results);
        end

        function annot = bml_annot_reorder_vars(app,x, vars)
            % BML_ANNOT_REORDER_VARS changes the order of vars in an annot table
            
            isvars = ismember(vars,x.Properties.VariableNames);
            vars = vars(isvars);
            othervars = ~ismember(x.Properties.VariableNames, vars);
            vars = [vars, x.Properties.VariableNames(othervars)];
            annot = x(:,vars);
        end

        %function results = plot_ecog(app)
        function plot_ecog(app)
            % plot ecog data
            %app.x_axis = app.FT_file.time{1,app.TRIAL};
            %app.y_axis = app.FT_file.trial{1,app.TRIAL}(app.ELECTRODE,:);
            %app.ecog_plot = plot(app.UIAxes,app.x_axis,app.y_axis);

            %{
            freqmap = app.FT_file{1,1}.trial{1,app.TRIAL}(app.ELECTRODE,:);
            for i=2:(length(app.FT_file))
                freqmap = vertcat(app.FT_file{1,i}.trial{1,app.TRIAL}(app.ELECTRODE,:),freqmap);
            end
            %}
            rownum_timings = calculate_rownum(app,3); % row nnumber for timings file
            rownum_stim = calculate_rownum(app,1);

            % changing the format of how the frequencies are stored
            switch app.TASK
                case 'triplet'
                    freqmap = permute(app.FT_file.trial{app.SESSION,app.TRIAL}(app.ELECTRODE,:,:), [3, 2, 1]); % flips columns 1 and 3 (rows become frequency and columns become time)
                case 'SMSL'
                    freqmap = permute(app.FT_file.trial{1,app.TRIAL}(app.ELECTRODE,:,:), [3, 2, 1]);
            end
            freqmap = reshape(freqmap, size(freqmap, 1), []); % make it a 2D matrix as opposed to a 3D matrix
            freqmap = flipud(freqmap); % flip the matrix upside down

            %{
            switch app.TASK
                case 'triplet'
                    % add x_loc based on timings file (start, end)
                    x_loc = zeros(2,1);
                    x_loc(1) = app.stimulus_file.starts(app.TRIAL);

                    if ~isnan(app.timings_file.ends(rownum_timings)) % third syllable offset
                        % if the speech off of the third syllable exists, use that
                        x_loc(2) = app.timings_file.ends(rownum_timings) + 1.5;
                    elseif ~isnan(app.timings_file.starts(rownum_timings)) % third syllable onset
                        x_loc(2) = app.timings_file.starts(rownum_timings) + 1.5;
                    elseif ~isnan(app.timings_file.ends(rownum_timings-1)) % second syllable offset
                        x_loc(2) = app.timings_file.ends(rownum_timings-1) + 1.5;
                    elseif ~isnan(app.timings_file.starts(rownum_timings-1)) % second syllable onset
                        x_loc(2) = app.timings_file.starts(rownum_timings-1) + 1.5;
                    elseif ~isnan(app.timings_file.ends(rownum_timings-2)) % first syllable offset
                        x_loc(2) = app.timings_file.ends(rownum_timings-2) + 1.5;
                    elseif ~ isnan(app.timings_file.starts(rownum_timings-2)) % first syllable onset
                        x_loc(2) = app.timings_file.starts(rownum_timings-2) + 1.5;
                    else
                        x_loc(2) = app.stimulus_file.ends(app.TRIAL) + 4;
                    end
                case 'SMSL'
                    %x_loc = [app.timings_file.sp_on(app.TRIAL),app.timings_file.sp_off(app.TRIAL)];
                    x_loc = zeros(2,1);
                    x_loc(1) = app.timings_file.audio_onset(app.TRIAL) - 0.5;
                    if isnan(app.timings_file.sp_off(app.TRIAL)) && ~isnan(app.timings_file.keypress_time(app.TRIAL))
                        x_loc(2) = app.timings_file.keypress_time(app.TRIAL); % if the trial is unusable and there is a keypress time it will instead get data ending at the keypress time
                        %disp('option 1');
                    elseif isnan(app.timings_file.sp_off(app.TRIAL)) && isnan(app.timings_file.keypress_time(app.TRIAL))
                        x_loc(2) = (app.timings_file.audio_onset(app.TRIAL) - 0.5) + 5;
                        %disp('option 2');
                    else
                        x_loc(2) = app.timings_file.sp_off(app.TRIAL) + 1.5;
                        %disp('option 3');
                    end
            end
            %}
            x_loc(1) = app.FT_file.time{1,app.TRIAL}(1);
            x_loc(2) = app.FT_file.time{1,app.TRIAL}(end);

            y_loc = [10^0,10^2.3];
            image(app.UIAxes,x_loc,y_loc,freqmap,'CDataMapping','scaled');
            app.UIAxes.YDir = 'reverse';
            %app.UIAxes.YScale = 'log';
            app.UIAxes.YTick = [1, 25, 50, 75, 100, 125, 150, 175, 10^2.3];
            %app.UIAxes.YTickLabel = {'1', '1.8', '3.6', '7.0', '13.7', '26.7', '52.2', '102.1', '199.5'};
            app.UIAxes.YTickLabel = {'199.5', '102.1', '52.2', '26.7', '13.7', '7.0', '3.6', '1.8', '1'};
            app.UIAxes.XLabel.String = 'time (s)';
            app.UIAxes.YLabel.String = 'Frequency (Hz)';
            app.UIAxes.Title.String = sprintf('Trial %d', app.TRIAL);

            % ylines at Hz values of 60,100,150
            % HOW TO PUT THESE IN THE RIGHT PLACE (WHAT TO SET THE Y AXIS TO)
            %yline(app.UIAxes,60,'--m','60 Hz');
            %yline(app.UIAxes,100,'--m','100 Hz');
            %yline(app.UIAxes,150,'--m','150 Hz');

            % trial timepoints
            switch app.TASK
                case 'triplet'    
                    % replace with same timepoints as in SMSL
                    xline(app.UIAxes,app.stimulus_file.starts(rownum_stim),'--c'); % start of stim
                    xline(app.UIAxes,app.stimulus_file.ends(rownum_stim),'--c'); % end of stim

                    xline(app.UIAxes,app.timings_file.starts(rownum_timings),'--k'); % start of syllable 1
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings),'--k'); % end of syllable 1
                    xline(app.UIAxes,app.timings_file.starts(rownum_timings+1),'--k'); % start of syllable 2
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings+1),'--k'); % end of syllable 2
                    xline(app.UIAxes,app.timings_file.starts(rownum_timings+2),'--k'); % start of syllable 3
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings+2),'--k'); % end of syllable 3
                case 'SMSL'
                    %xline(app.UIAxes,[app.timings_file.audio_onset(app.TRIAL) - 0.5],'--k'); % start the trial
                    xline(app.UIAxes,app.timings_file.audio_onset(app.TRIAL),'--c'); % audio stim start
                    xline(app.UIAxes,app.timings_file.audio_offset(app.TRIAL),'--c'); % audio stim stop
                    if ~isnan(app.timings_file.audio_go_onset(app.TRIAL)) % if there is a go beep (not NaN)
                        xline(app.UIAxes,app.timings_file.audio_go_onset(app.TRIAL),'--g'); % go beep
                    end
                    if app.timings_file.is_stoptrial(app.TRIAL) == 1 % if its a stop trial
                        xline(app.UIAxes,app.timings_file.audio_stop_onset(app.TRIAL),'--r'); % stop beep
                    end
                    xline(app.UIAxes,app.timings_file.sp_on(app.TRIAL),'--k'); % speech onset
                    xline(app.UIAxes,app.timings_file.sp_off(app.TRIAL),'--k'); % speech offset
                    %xline(app.UIAxes,[app.timings_file.sp_off(app.TRIAL) + 1.5],'--k'); % end the trial
            end

            %% raw data
            switch app.TASK
                case 'triplet'
                    x_data = app.FT_raw.time{app.SESSION,app.TRIAL}(1,:);
                    y_data = app.FT_raw.trial{app.SESSION,app.TRIAL}(app.ELECTRODE,:);
                case 'SMSL'
                    x_data = app.FT_raw.time{1,app.TRIAL}(1,:);
                    y_data = app.FT_raw.trial{1,app.TRIAL}(app.ELECTRODE,:);
            end
            plot(app.UIAxes_2, x_data,y_data);

            % trial timepoints
            switch app.TASK
                case 'triplet'    
                    % replace with same timepoints as in SMSL
                    xline(app.UIAxes_2,app.stimulus_file.starts(rownum_stim),'--c'); % start of stim
                    xline(app.UIAxes_2,app.stimulus_file.ends(rownum_stim),'--c'); % end of stim

                    xline(app.UIAxes,app.timings_file.starts(rownum_timings),'--k'); % start of syllable 1
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings),'--k'); % end of syllable 1
                    xline(app.UIAxes,app.timings_file.starts(rownum_timings+1),'--k'); % start of syllable 2
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings+1),'--k'); % end of syllable 2
                    xline(app.UIAxes,app.timings_file.starts(rownum_timings+2),'--k'); % start of syllable 3
                    xline(app.UIAxes,app.timings_file.ends(rownum_timings+2),'--k'); % end of syllable 3
                case 'SMSL'
                    %xline(app.UIAxes_2,[app.timings_file.audio_onset(app.TRIAL) - 0.5],'--k'); % start the trial
                    xline(app.UIAxes_2,app.timings_file.audio_onset(app.TRIAL),'--c'); % audio stim start
                    xline(app.UIAxes_2,app.timings_file.audio_offset(app.TRIAL),'--c'); % audio stim stop
                    if ~isnan(app.timings_file.audio_go_onset(app.TRIAL)) % if there is a go beep (not NaN)
                        xline(app.UIAxes_2,app.timings_file.audio_go_onset(app.TRIAL),'--g'); % go beep
                    end
                    if app.timings_file.is_stoptrial(app.TRIAL) == 1 % if its a stop trial
                        xline(app.UIAxes_2,app.timings_file.audio_stop_onset(app.TRIAL),'--r'); % stop beep
                    end
                    xline(app.UIAxes_2,app.timings_file.sp_on(app.TRIAL),'--k'); % speech onset
                    xline(app.UIAxes_2,app.timings_file.sp_off(app.TRIAL),'--k'); % speech offset
                    %xline(app.UIAxes_2,[app.timings_file.sp_off(app.TRIAL) + 1.5],'--k'); % end the trial
            end
        end
        
        function dispInfo(app)
            switch app.TASK
                case 'triplet'
                    app.TabGroup.SelectedTab = app.tripletTab;

                    rownum_t = calculate_rownum(app,3); % rownum for the timings file
                    rownum_a = calculate_rownum(app,6); % rownum for the accuracy file

                    % display the stim word
                    %app.wordTextArea_2.Value = [app.timings_file.stim(rownum_t) ' ' app.timings_file.stim(rownum_t+1) ' ' app.timings_file.stim(rownum_t+2)];
                    app.wordTextArea_2.Value = append(app.timings_file.stim(rownum_t),' ',app.timings_file.stim(rownum_t+1),' ',app.timings_file.stim(rownum_t+2));

                    % display if any of the timepoints are missing
                    if isnan(app.timings_file.starts(rownum_t)) || isnan(app.timings_file.ends(rownum_t)) || isnan(app.timings_file.starts(rownum_t+1)) || isnan(app.timings_file.ends(rownum_t+1)) || isnan(app.timings_file.starts(rownum_t+2)) || isnan(app.timings_file.ends(rownum_t+2))
                        app.missing_timing_dataTextArea.Value = 'true';
                    else
                        app.missing_timing_dataTextArea.Value = 'false';
                    end
                    
                    % display the accuracy
                    app.stvowelTextArea.Value = app.accuracy_file.accuracy(rownum_a);
                    app.stconsonantTextArea.Value = app.accuracy_file.accuracy(rownum_a+1);
                    app.ndvowelTextArea.Value = app.accuracy_file.accuracy(rownum_a+2);
                    app.ndconsonantTextArea.Value = app.accuracy_file.accuracy(rownum_a+3);
                    app.rdvowelTextArea.Value = app.accuracy_file.accuracy(rownum_a+4);
                    app.rdconsonantTextArea.Value = app.accuracy_file.accuracy(rownum_a+5);
                case 'SMSL'
                    app.TabGroup.SelectedTab = app.SMSLTab;

                    % display the stim word
                    app.wordTextArea.Value = app.timings_file.word(app.trialNo);
        
                    % display onset error
                    if isnan(app.timings_file.onset_error(app.trialNo)) || isempty(app.timings_file.onset_error(app.trialNo))
                        app.onset_errorTextArea.Value = 'No Error';
                    else
                        app.onset_errorTextArea.Value = string(app.timings_file.onset_error(app.trialNo));
                    end
        
                    % display rime error
                    if isnan(app.timings_file.rime_error(app.trialNo)) || isempty(app.timings_file.rime_error(app.trialNo))
                        app.rime_errorTextArea.Value = 'No Error';
                    else
                        app.rime_errorTextArea.Value = string(app.timings_file.rime_error(app.trialNo));
                    end
        
                    % display if the trial is a stop trial
                    if app.timings_file.is_stoptrial(app.trialNo) == 0
                        app.is_stoptrialTextArea.Value = 'false';
                    else
                        app.is_stoptrialTextArea.Value = 'true';
                    end
        
                    % display the stop response type
                    if app.timings_file.stop_response(app.trialNo) == 1
                        app.stop_responseTextArea.Value = 'No Start';
                    elseif app.timings_file.stop_response(app.trialNo) == 2
                        app.stop_responseTextArea.Value = 'Interrupted';
                    elseif app.timings_file.stop_response(app.trialNo) == 3
                        app.stop_responseTextArea.Value = 'Completed';
                    else
                        app.stop_responseTextArea.Value = 'NaN';
                    end
        
                    % display if the trial has been marked usable or not
                    if app.timings_file.unusable_trial(app.TRIAL) == 0
                        app.unusable_trialTextArea.Value = 'Usable Trial';
                    elseif app.timings_file.unusable_trial(app.TRIAL) == 1
                        app.unusable_trialTextArea.Value = 'Unusable Trial';
                    elseif app.timings_file.is_stoptrial(app.TRIAL) == 1
                        app.unusable_trialTextArea.Value = 'Stop Trial';
                    else
                        app.unusable_trialTextArea.Value = 'NaN';
                    end
            end
        end
    
        function rownum = calculate_rownum(app,n)
            % n = number of rows per trial
            % calculates the first row number in an annot file for the current trial based on the number of rows per trial

            curr_ses_row = app.TRIAL*n - (n-1);

            if app.SESSION > 1
                prev_ses = sum(app.session_list(1,1:(1-app.SESSION)))*n;
                rownum = curr_ses_row + prev_ses;
            else
                rownum = curr_ses_row;
            end
        end

        function subElec = subelec_dlg(app)
            %var_not_correct = true;
            %while var_not_correct
                switch app.TASK
                    case 'triplet'
                        title = 'Subject, Electrode';
                        prompts = {'Subject:','Electrode Label:','Session:','Trial:'};
                        dims = [1,20];
                        definput = {'DBS','','',''};
                        subElec = inputdlg(prompts,title,dims,definput);
            
                        waitfor(subElec);
    
                        app.SUBJECT = subElec{1};
                        app.SESSION = str2double(subElec{3});
                        app.TRIAL = str2double(subElec{4});
                    case 'SMSL'
                        title = 'Subject and Electrode';
                        prompts = {'Subject:','Electrode Label:','Trial:'}; % need to copy to triplet
                        dims = [1,20];
                        definput = {'sub-DM10','',''}; % need to copy to triplet
                        subElec = inputdlg(prompts,title,dims,definput); % need to copy to triplet
            
                        waitfor(subElec);
    
                        app.SUBJECT = subElec{1};
                        app.TRIAL = str2num(subElec{3});
                        app.SESSION = 1;
                end

                app.UIFigure.Name = app.SUBJECT;
    
                % if any of the elements are empty, user probably clicked cancel so the GUI should end
                if isempty(app.SUBJECT) || isempty(app.SESSION) || isempty(app.TRIAL) || isempty(subElec{2})
                    disp('User clicked cancel, closing program');
                    %delete(app);
                    error('User clicked cancel or fields are empty');
                end
    
                % if the subject name is invalid, loop back to the start
                ismember_array = ismember(app.SUBJECT,app.valid_sub);
                if isempty(ismember_array)
                    disp('Subject name is invalid, please enter a valid name');
                    error('Subject name is invalid');
                    %continue
                end

                % load tsv files
                switch app.TASK
                    case 'triplet'
                        app.PATH_DATASET = 'Z:\DBS';
                        app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                        app.PATH_PREPROC_DATA = [app.PATH_SUB filesep 'Preprocessed Data'];
                        app.PATH_FT = [app.PATH_PREPROC_DATA filesep 'FieldTrip'];
                        app.PATH_SYNC = [app.PATH_PREPROC_DATA filesep 'Sync'];
                        app.PATH_ANNOT = [app.PATH_SYNC filesep 'annot'];
        
                        app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';
    
                        app.raw_filename = [app.SUBJECT '_ft_raw_session_trial'];
        
                        app.FT_filename = [app.SUBJECT '_ft_raw_session_freqbands-trial.mat'];
    
                        % loading the timings file
                        app.timings_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_syllable.txt'],'Delimiter','\t');
    
                        % loading the stimulus file
                        app.stimulus_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_stimulus_triplet'],'Delimiter','\t');
                        
                        app.electrode_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_electrode'],'Delimiter','\t');
    
                        % load the file with the accuracy information
                        app.accuracy_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_phoneme'],'Delimiter','\t');
                    case 'SMSL'
                        app.PATH_DATASET = 'Y:\DBS\derivatives';
                        app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                        app.PATH_ANNOT = [app.PATH_SUB filesep 'annot'];
                        app.PATH_FT = [app.PATH_SUB filesep 'fieldtrip'];
    
                        app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';
    
                        app.raw_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_trial'];
                        %app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_freqbands'];
                        % changing the format of how the frequencies are stored
                        app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_freqbands-trial.mat'];
    
                        %loading the timings file
                        app.timings_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_task-smsl_annot-produced-syllables.tsv']);
                        %app.timings_file.starts
    
                        % load the electrode file
                        app.electrode_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_electrodes.tsv']);
    
                        % load the file with connector information
                        app.channels_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_channels.tsv']);
                end

                % check that the session is valid
                if strcmp(app.TASK,'triplet')
                    numses = app.timings_file.session_id(end);

                    if app.SESSION > numses
                        disp('Entered session is too large');
                        error('Session is too large');
                        %continue
                    end
                end

                % check that the trial number is valid
                switch app.TASK
                    case 'triplet'
                        temp_indx = find(app.timings_file.session_id == app.SESSION);
                        temp = app.timings_file.session_id(temp_indx);
                        numtrial = temp(end);

                        if app.TRIAL > numtrial
                            disp('Entered trial is too large');
                            error('Trial is too large');
                            %continue
                        end
                    case 'SMSL'
                        numtrial = app.timings_file.trial_id(end);

                        if app.TRIAL > numtrial
                            disp('Entered trial is too large');
                            error('Trial is too large');
                            %continue
                        end
                end

                %var_not_correct = false;
            %end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ask for subject, trial and electrode input
            app.TASK = questdlg('triplet or SMSL?','task','SMSL','triplet','triplet');

            waitfor(app.TASK);

            % defining paths
            switch app.TASK
                case 'triplet'
                    subElec = subelec_dlg(app);

                    %{
                    title = 'Subject, Electrode';
                    prompts = {'Subject:','Electrode:','Session:','Trial:'};
                    dims = [1,20];
                    definput = {'DBS','','',''};
                    subElec = inputdlg(prompts,title,dims,definput);
        
                    waitfor(subElec);

                    app.SUBJECT = subElec{1};
                    app.SESSION = str2double(subElec{3});
                    app.TRIAL = str2double(subElec{4});
                    %app.ELECTRODE = str2num(subElec{2});

                    % make sure inputted subject name is correctly formatted
                    %if isempty(regexp(app.SUBJECT,'DBS30\d\d'))
                    ismember_array = ismember(app.SUBJECT,app.valid_triplet);
                    if isempty(ismember_array)
                        error('invalid subject name');
                    end

                    app.PATH_DATASET = 'Z:\DBS';
                    app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                    app.PATH_PREPROC_DATA = [app.PATH_SUB filesep 'Preprocessed Data'];
                    app.PATH_FT = [app.PATH_PREPROC_DATA filesep 'FieldTrip'];
                    app.PATH_SYNC = [app.PATH_PREPROC_DATA filesep 'Sync'];
                    app.PATH_ANNOT = [app.PATH_SYNC filesep 'annot'];
    
                    app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';

                    raw_filename = [app.SUBJECT '_ft_raw_session_trial'];
    
                    app.FT_filename = [app.SUBJECT '_ft_raw_session_freqbands-trial.mat'];

                    % loading the timings file
                    app.timings_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_syllable.txt'],'Delimiter','\t');

                    % loading the stimulus file
                    app.stimulus_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_stimulus_triplet'],'Delimiter','\t');
                    
                    app.electrode_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_electrode'],'Delimiter','\t');

                    % load the file with the accuracy information
                    app.accuracy_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_phoneme'],'Delimiter','\t');
                    %}
                case 'SMSL'
                    subElec = subelec_dlg(app);

                    %{
                    title = 'Subject and Electrode';
                    prompts = {'Subject:','Electrode Label:','Trial:'}; % need to copy to triplet
                    dims = [1,20];
                    definput = {'sub-DM10','',''}; % need to copy to triplet
                    subElec = inputdlg(prompts,title,dims,definput); % need to copy to triplet
        
                    waitfor(subElec);
        
                    % need to copy to triplet
                    if isempty(subElec)
                        error('No answer provided for subject, electrode, or trial. User clicked cancel.');
                    else
                        app.SUBJECT = subElec{1};
                        app.TRIAL = str2num(subElec{3}); 
                    end
                    %
                    %disp(subElec{3});
                    %fprintf('app.TRIAL: %d',subElec{3});

                    % make sure inputted subject name is correctly formatted
                    ismember_array = ismember(app.SUBJECT,app.valid_SMSL);
                    %if isempty(regexp(app.SUBJECT,'sub-DM10\d\d'))
                    if isempty(ismember_array)
                        error('invalid subject name');
                    end  

                    % need to check if trial value is valid (cannot be larger than number of trials and cannot be negative)

                    app.PATH_DATASET = 'Y:\DBS\derivatives';
                    app.PATH_SUB = [app.PATH_DATASET filesep app.SUBJECT];
                    app.PATH_ANNOT = [app.PATH_SUB filesep 'annot'];
                    app.PATH_FT = [app.PATH_SUB filesep 'fieldtrip'];

                    app.FilePath = 'C:\Users\amsmeier\ieeg_ft_funcs_am\manual_artifact_detection\temp_storage';

                    raw_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_trial'];
                    %app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_freqbands'];
                    % changing the format of how the frequencies are stored
                    app.FT_filename = [app.SUBJECT '_ses-intraop_task-smsl_ft-raw_freqbands-trial.mat'];

                    %loading the timings file
                    app.timings_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_task-smsl_annot-produced-syllables.tsv']);
                    %app.timings_file.starts

                    % load the electrode file
                    app.electrode_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_electrodes.tsv']);

                    % load the file with connector information
                    app.channels_file = bml_annot_read_tsv(app,[app.PATH_ANNOT filesep app.SUBJECT '_ses-intraop_channels.tsv']);
                    %}
                otherwise
                    disp('User clicked cancel, closing program');
                    %closeApp(app,false);
                    %delete(app);
                    error('User clicked cancel');
            end

            disp('loading data');

            % loading the fieldtrip file
            tempname = load([app.PATH_FT filesep app.FT_filename]);
            app.FT_file = tempname.trialed;

            % loading raw fieldtrip data file
            tempname2 = load([app.PATH_FT filesep app.raw_filename]);
            app.FT_raw = tempname2.D_trialed;
            
            app.filename = [app.SUBJECT '_ses-intraop_task-' app.TASK '_artifact-manual.tsv'];

            % finding the row number based on electrode input
            elec_arr = ismember(app.FT_file.label,subElec{2});
            %elec_arr = ismember(app.electrode_file.name,subElec{2});
            % checking to make sure electrode name is valid
            if isempty(find(elec_arr))
                error('invalid electrode name');
            end
            app.ELECTRODE = find(elec_arr);
            
            % defining how many total trials there are
            %app.allTrials = dir(app.ImagePath);
            %size1 = size(app.FT_file{1,1}.trial);
            app.session_list = zeros([1,length(app.FT_file.trial(:,1))]);
            switch app.TASK
                case 'triplet'
                    app.totalTrials = 0;
                    for i = 1:length(app.FT_file.trial(:,1)) % number of sessions
                        temp = app.FT_file.trial(i,:);
                        temp = temp(~cellfun('isempty',temp));

                        app.totalTrials = app.totalTrials + length(temp);
                        app.session_list(i) = length(temp);
                    end
                case 'SMSL'
                    size1 = size(app.FT_file.trial);
                    app.totalTrials = size1(2);  
                    %app.totalTrials = 2;
            end

            app.count = 1;
            app.trialNo = 1;

            % determine if the program has been run before and plot the data
            if hasBeenRun(app)
                fprintf('program has been run before \n');

                % load data from file
                app.results = bml_annot_read_tsv(app,[app.FilePath filesep app.filename]);
                %app.results = readtable([app.FilePath filesep app.filename], 'Filetype','text','Delimiter','\t','TextType','string');
                %app.results.Properties.VariableNames = ["trial","starts","ends","classification","comments"];
                %app.results

                %app.results(:,"classification") = string(app.results{:,"classification"});
                %app.results(:,"comments") = string(app.results{:,"comments"});
                
                app.commentsEditField.Value = string(app.results.comments{app.trialNo});
                app.classificationEditField.Value = string(app.results.classification{app.trialNo});
                app.tempResult = app.results.classification{app.trialNo};

                app.finishedtrialButton.Enable = 'on';
            else
                fprintf('program has not been run before \n');

                num_electrodes = length(app.FT_file.label);
                num_rows = num_electrodes*app.totalTrials;

                % create an empty table with one column
                switch app.TASK
                    case 'triplet'
                        app.results = table('Size',[num_rows, 10],'VariableTypes',["double","double","string","string","single","single","single","single","string","string"]);
                        app.results.Properties.VariableNames = ["starts","ends","label","type","connector","trial","session","missing_timing_data","classification","comments"];

                        % populate file with trial, session, and electrode names
                        for i = 1:num_electrodes % loop through each electrode
                            % rows in the results file that corrospond to the current electrode
                            curr_end = i*app.totalTrials;
                            curr_begin = curr_end - (app.totalTrials - 1);
    
                            app.results.label(curr_begin:curr_end) = app.FT_file.label(i); % electrode label
                            app.results.trial(curr_begin:curr_end) = app.timings_file.trial_id(3:3:end); % trial id
                            app.results.session(curr_begin:curr_end) = app.timings_file.session_id(3:3:end); % session id
                        end
                    case 'SMSL'
                        app.results = table('Size',[num_rows, 9],'VariableTypes',["double","double","string","string","single","single","single","string","string"]);
                        app.results.Properties.VariableNames = ["starts","ends","label","type","connector","trial","unusable_trial","classification","comments"];

                        % populate file with trial and electrode names
                        for i = 1:num_electrodes % number of electrodes
                            % rows in the results file that corrospond to the current electrode
                            curr_end = i*app.totalTrials;
                            curr_begin = curr_end - (app.totalTrials - 1);

                            app.results.label(curr_begin:curr_end) = app.FT_file.label(i); % electrode label
                            app.results.trial(curr_begin:curr_end) = app.timings_file.trial_id; % trial id
                        end
                end

                app.finishedtrialButton.Enable = 'off';
            end

            dispInfo(app);

            plot_ecog(app);
        end

        % Button pushed function: cleanButton
        function cleanButtonPushed(app, event)
            % put 'yes' in the answers column for the number trial it is
            %app.results(app.trialNo,1) = 1; 
            %fprintf('yes, %d \n',  app.count);

            app.tempResult = "clean";
            app.classificationEditField.Value = "clean";

            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: artifactualButton
        function artifactualButtonPushed(app, event)
            % put 'no' in the answers column instead of 'yes'
            %app.results(app.trialNo,1) = 0;
            %fprintf('no, %d \n',  app.count);

            app.tempResult = "artifactual";
            app.classificationEditField.Value = "artifactual";

            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: questionableButton
        function questionableButtonPushed(app, event)
            app.tempResult = "questionable";
            app.classificationEditField.Value = "questionable";

            app.finishedtrialButton.Enable = 'on';
        end

        % Button pushed function: finishedtrialButton
        function finishedtrialButtonPushed(app, event)
            app.results.trial(app.trialNo) = app.trialNo;
            %app.tempResult
            app.results.classification(app.trialNo) = app.tempResult;
            app.results.comments(app.trialNo) = string(app.commentsEditField.Value);

            % putting info into the results table
            switch app.TASK
                case 'triplet'
                    rownum_e = calculate_rownum(app,1);
                    rownum_t = calculate_rownum(app,3); % rownum for the timings file

                    % starts and ends
                    app.results.starts(app.trialNo) = app.FT_file.time{app.SESSION,app.TRIAL}(1);
                    app.results.ends(app.trialNo) = app.FT_file.time{app.SESSION,app.TRIAL}(end);

                    % type
                    app.results.type(app.trialNo) = app.electrode_file.type(rownum_e);

                    % connector
                    app.results.connector(app.trialNo) = app.electrode_file.connector(rownum_e);

                    % missing_timing_data
                    if isnan(app.timings_file.starts(rownum_t)) || isnan(app.timings_file.ends(rownum_t)) || isnan(app.timings_file.starts(rownum_t+1)) || isnan(app.timings_file.ends(rownum_t+1)) || isnan(app.timings_file.starts(rownum_t+2)) || isnan(app.timings_file.ends(rownum_t+2))
                        app.results.missing_timing_data(app.trialNo) = 1;
                    else
                        app.results.missing_timing_data(app.trialNo) = 0;
                    end
                case 'SMSL'
                    % starts and ends
                    app.results{app.trialNo,"starts"} = app.FT_file.time{app.TRIAL}(1);
                    app.results{app.trialNo,"ends"} = app.timings_file.ends(app.trialNo);

                    % type
                    app.results.type(app.trialNo) = app.electrode_file.type(app.TRIAL);

                    % connector
                    app.results.connector(app.trialNo) = app.channels_file.connector(app.TRIAL);

                    % unusable_trial
                    app.results.unusable_trial(app.trialNo) = app.timings_file.unusable_trial(app.TRIAL);
            end

            if iscell(app.tempResult)
                fprintf('result: %s \n', app.tempResult{1});
            else
                fprintf('result: %s \n', app.tempResult);
            end
            fprintf('text box: %s \n', app.commentsEditField.Value);

            app.commentsEditField.Value = '';
            app.classificationEditField.Value = '';

            if app.trialNo == app.totalTrials
                disp('Reached the end of the trials. Closing app and saving results');
                % end the app
                closeApp(app,'true');
            else
                if hasBeenRun(app) && (app.results{app.trialNo,"classification"} ~= "")
                    % check if there is already data for the trial
                    if app.results.starts(app.trialNo) ~= 0
                        app.commentsEditField.Value = string(app.results{app.trialNo+1,"comments"});
                        app.classificationEditField.Value = app.results{app.trialNo+1,"classification"}{1};
                        app.tempResult = app.results{app.trialNo+1,"classification"};
                    end

                    % if there is then set the value of the text box to the
                    % current value
                    
                    updateGraphandCount(app);

                    app.finishedtrialButton.Enable = 'on';
                else
                    % just update the graph
                    updateGraphandCount(app);

                    app.finishedtrialButton.Enable = 'off';
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % check if what results have been generated should be saved
            % before closing the program

            %checkToCloseApp(app);
            %app.SaveChangesApp = guiTest_saveChanges(app);

            disp('close request');

            fig = uifigure;
            msg = "Would you like to save changes?";
            title = "Save Changes";
            saveRequest = uiconfirm(fig,msg,title, ...
                'Options',{'Yes','No'}, ...
                'CloseFcn',@(h,e)close(fig));

            switch saveRequest
                case "Yes"
                    delete(saveRequest);
                    closeApp(app,true);
                case "No"
                    delete(saveRequest);
                    closeApp(app,false);
            end
            
            %app.results{app.trialNo,"trial"} = app.trialNo;
            %app.results{app.trialNo,"classification"} = app.tempResult;
            %app.results{app.trialNo,"comments"} = string(app.commentsEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trial')
            ylabel(app.UIAxes, 'frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'mV')
            zlabel(app.UIAxes_2, 'Z')
            app.cleanButton = uibutton(app.UIFigure, 'push');
            app.cleanButton.ButtonPushedFcn = createCallbackFcn(app, @cleanButtonPushed, true);
            app.cleanButton.Text = 'clean';
            app.artifactualButton = uibutton(app.UIFigure, 'push');
            app.artifactualButton.ButtonPushedFcn = createCallbackFcn(app, @artifactualButtonPushed, true);
            app.artifactualButton.Text = 'artifactual';
            app.questionableButton = uibutton(app.UIFigure, 'push');
            app.questionableButton.ButtonPushedFcn = createCallbackFcn(app, @questionableButtonPushed, true);
            app.questionableButton.Text = 'questionable';
            app.commentsEditFieldLabel = uilabel(app.UIFigure);
            app.commentsEditFieldLabel.Text = 'comments';
            app.commentsEditField = uieditfield(app.UIFigure, 'text');
            app.finishedtrialButton = uibutton(app.UIFigure, 'push');
            app.finishedtrialButton.ButtonPushedFcn = createCallbackFcn(app, @finishedtrialButtonPushed, true);
            app.finishedtrialButton.Text = 'finished trial';
            app.classificationEditFieldLabel = uilabel(app.UIFigure);
            app.classificationEditFieldLabel.Text = 'classification:';
            app.classificationEditField = uieditfield(app.UIFigure, 'text');
            app.classificationEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SMSLTab = uitab(app.TabGroup);
            app.SMSLTab.Title = 'SMSL';
            app.is_stoptrialTextAreaLabel = uilabel(app.SMSLTab);
            app.is_stoptrialTextAreaLabel.Text = 'is_stoptrial';
            app.is_stoptrialTextArea = uitextarea(app.SMSLTab);
            app.is_stoptrialTextArea.Editable = 'off';
            app.onset_errorTextAreaLabel = uilabel(app.SMSLTab);
            app.onset_errorTextAreaLabel.Text = 'onset_error';
            app.onset_errorTextArea = uitextarea(app.SMSLTab);
            app.onset_errorTextArea.Editable = 'off';
            app.wordTextAreaLabel = uilabel(app.SMSLTab);
            app.wordTextAreaLabel.Text = 'word';
            app.wordTextArea = uitextarea(app.SMSLTab);
            app.wordTextArea.Editable = 'off';
            app.stop_responseTextAreaLabel = uilabel(app.SMSLTab);
            app.stop_responseTextAreaLabel.Text = 'stop_response';
            app.stop_responseTextArea = uitextarea(app.SMSLTab);
            app.stop_responseTextArea.Editable = 'off';
            app.unusable_trialTextAreaLabel = uilabel(app.SMSLTab);
            app.unusable_trialTextAreaLabel.Text = 'unusable_trial';
            app.unusable_trialTextArea = uitextarea(app.SMSLTab);
            app.unusable_trialTextArea.Editable = 'off';
            app.rime_errorTextAreaLabel = uilabel(app.SMSLTab);
            app.rime_errorTextAreaLabel.Text = 'rime_error';
            app.rime_errorTextArea = uitextarea(app.SMSLTab);
            app.rime_errorTextArea.Editable = 'off';
            app.tripletTab = uitab(app.TabGroup);
            app.tripletTab.Title = 'triplet';
            app.wordTextArea_2Label = uilabel(app.tripletTab);
            app.wordTextArea_2Label.Text = 'word';
            app.wordTextArea_2 = uitextarea(app.tripletTab);
            app.wordTextArea_2.Editable = 'off';
            app.missing_timing_dataTextAreaLabel = uilabel(app.tripletTab);
            app.missing_timing_dataTextAreaLabel.Text = 'missing_timing_data';
            app.missing_timing_dataTextArea = uitextarea(app.tripletTab);
            app.missing_timing_dataTextArea.Editable = 'off';
            app.AccuracyLabel = uilabel(app.tripletTab);
            app.AccuracyLabel.Text = 'Accuracy';
            app.stvowelTextAreaLabel = uilabel(app.tripletTab);
            app.stvowelTextAreaLabel.Text = '1st vowel';
            app.stvowelTextArea = uitextarea(app.tripletTab);
            app.stvowelTextArea.Editable = 'off';
            app.stconsonantTextAreaLabel = uilabel(app.tripletTab);
            app.stconsonantTextAreaLabel.Text = '1st consonant';
            app.stconsonantTextArea = uitextarea(app.tripletTab);
            app.stconsonantTextArea.Editable = 'off';
            app.stvowelLabel = uilabel(app.tripletTab);
            app.stvowelLabel.Text = '2nd vowel';
            app.ndvowelTextArea = uitextarea(app.tripletTab);
            app.ndvowelTextArea.Editable = 'off';
            app.stconsonantLabel = uilabel(app.tripletTab);
            app.stconsonantLabel.Text = '2nd consonant';
            app.ndconsonantTextArea = uitextarea(app.tripletTab);
            app.ndconsonantTextArea.Editable = 'off';
            app.rdvowelTextAreaLabel = uilabel(app.tripletTab);
            app.rdvowelTextAreaLabel.Text = '3rd vowel';
            app.rdvowelTextArea = uitextarea(app.tripletTab);
            app.rdvowelTextArea.Editable = 'off';
            app.rdconsonantTextAreaLabel = uilabel(app.tripletTab);
            app.rdconsonantTextAreaLabel.Text = '3rd consonant';
            app.rdconsonantTextArea = uitextarea(app.tripletTab);
            app.rdconsonantTextArea.Editable = 'off';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecog_artifact_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end