classdef ecog_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UpdateGraphPanel         matlab.ui.container.Panel
        ElectrodeEditField       matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel  matlab.ui.control.Label
        TrialEditField           matlab.ui.control.NumericEditField
        TrialEditFieldLabel      matlab.ui.control.Label
        SubjectEditField         matlab.ui.control.EditField
        SubjectEditFieldLabel    matlab.ui.control.Label
        UpdateGraphButton        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SUBJECT;
        TRIAL;
        ELECTRODE;

        Z_PATH_DATASET;
        PATH_SUB;
        PATH_PREPROC_DATA;
        PATH_FT;
        PATH_SYNC;
        PATH_ANNOT;
        
        FT_file;
        timings_file;
        FT_file_trialed;

        ecog_plot;
        time;
        data;

        FT_filename;
    end
    
    methods (Access = private)
        function env = bml_envelope_wavpow(cfg, data)
            % BML_ENVELOPE_WAVPOW calculates the power of the signal by wavelet
            % decomposition
            %
            % The envelope is the power of the convolution of the signal with a Morlet
            % wavelet.
            %
            % Use as
            %   env = bml_envelope_wavpow(data)
            %   env = bml_envelope_wavpow(cfg, data)
            %
            % cfg is a configuration struct with the following fields
            % cfg.out_freq - float: intended output sampling frequency (default 100Hz)
            % cfg.wav_freq - float: frequency in hertz of the wavelet
            % cfg.wav_width - float: width of the wavelet. (number of cycles in the gaussian)
            % cfg.wav_sigma_t - float: sigma parameter of the time domain gaussian
            %                   (only used if wav_width is not defined)
            % cfg.wav_sigma_f - float: sigma parameter in the frequency domain
            %                   (only used if wav_width and wav_sigma_t are not defined)
            %
            % data - FT_DATATYPE_RAW
            % 
            % Returns a FT_DATATYPE_RAW
            
            
            if nargin==1
              data=cfg;
              cfg=[];
            end
            
            if ~ismember('fsample',fields(data))
	            data.fsample = bml_getFs(data);
            end
            
            out_freq = bml_getopt_single(cfg,'out_freq',100);
            wav_freq = bml_getopt_single(cfg,'wav_freq',400);
            wav_sigma_t = bml_getopt_single(cfg,'wav_sigma_t', 7 ./ (2 .* pi .* wav_freq));
            wav_sigma_f = bml_getopt_single(cfg,'wav_sigma_f', 1 ./ (2 .* pi .* wav_sigma_t));
            wav_width   = bml_getopt_single(cfg,'wav_width', wav_freq ./ wav_sigma_f);
            
            cfg1=[];
            cfg1.fq = wav_freq;
            cfg1.width = wav_width;
            cfg1.keep_power = 'yes';
            cfg1.keep_amp = 'no';
            cfg1.downsample = 'average';
            cfg1.downsample_freq = out_freq;
            env = bml_wavtransform(cfg1,data);
            env.trial = env.power;
            env = rmfield(env,{'dimord','freq','power'});
            
            env.cfg = struct();
            env.cfg.envelope = "wavpow";
            env.cfg.wav_freq = wav_freq;
            env.cfg.wav_width = wav_width;
            
            if isfield(data,'cfg')
              env.cfg.previous = data.cfg;
            end
            
            if ismember('hdr',fieldnames(data))
              env.hdr = data.hdr;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SUBJECT = 'DBS3001';
            app.TRIAL = 1;
            app.ELECTRODE = 5;

            app.Z_PATH_DATASET = 'Z:\DBS';
            app.PATH_SUB = [app.Z_PATH_DATASET filesep app.SUBJECT];
            app.PATH_PREPROC_DATA = [app.PATH_SUB filesep 'Preprocessed Data'];
            app.PATH_FT = [app.PATH_PREPROC_DATA filesep 'FieldTrip'];
            app.PATH_SYNC = [app.PATH_PREPROC_DATA filesep 'Sync'];
            app.PATH_ANNOT = [app.PATH_SYNC filesep 'annot'];

            app.FT_filename = [app.SUBJECT '_ft_raw_session_freqbands_trialed.mat'];

            % loading the fieldtrip file
            tempname = load([app.PATH_FT filesep app.FT_filename]);
            %app.FT_file = tempname.D;
            app.FT_file = tempname.trialed;

            %disp(app.FT_file);
            %load([app.PATH_FT filesep app.FT_filename]);
            %load("Z:\DBS\DBS3001\Preprocessed Data\FieldTrip\DBS3001_ft_raw_filt_trial_ar_ref_criteria_D.mat");
            %app.y_axis = app.Ft_filename.trial{1,1};
            %disp(app.FT_filename);
            %disp(tempname);

            % loading the timings file
            app.timings_file = readmatrix([app.PATH_ANNOT filesep app.SUBJECT '_produced_syllable.txt'],'Delimiter','\t');
            %app.timings_file = readtable([app.PATH_ANNOT filesep app.SUBJECT '_produced_syllable.txt'],'Delimiter','\t');
            %app.timings_file(1,:)

            % plotting the ecog data
            %app.x_axis = app.FT_file{1,1}.time{1,app.TRIAL};
            %app.data = app.FT_file{1,1}.trial{1,app.TRIAL}(app.ELECTRODE,:);
            app.data = app.FT_file{1,1}.trial{1,app.TRIAL};

            %app.ecog_plot = plot(app.UIAxes,app.x_axis,app.y_axis);
            %app.ecog_plot = image(app.UIAxes,app.x_axis,app.data);

            % plotting the syllable onset and offsets
            rownum = 3*app.TRIAL - 2;
            %xline(app.UIAxes,36140);
            %app.timings_file(rownum,8)
            xline(app.UIAxes,app.timings_file(rownum,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum,3),'--r');
            xline(app.UIAxes,app.timings_file(rownum+1,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum+1,3),'--r');
            xline(app.UIAxes,app.timings_file(rownum+2,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum+2,3),'--r');
            
            %xline(app.timings_file(rownum,1));

            %y = ylim(app.UIAxes);
            %x11 = app.timings_file(rownum,1);
            %plot(app.UIAxes,[x11,x11],[y(1),y(2)]);
            
            %hold(app.UIAxes,'off');
        end

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            %delete(app.ecog_plot);
            %disp('deleted');

            app.SUBJECT = app.SubjectEditField.Value;
           
            app.TRIAL = app.TrialEditField.Value;
            app.ELECTRODE = app.ElectrodeEditField.Value;
            app.TrialEditField.Value
            app.ElectrodeEditField.Value

            app.time = app.FT_file.time{1,app.TRIAL};
            app.data = app.FT_file.trial{1,app.TRIAL}(app.ELECTRODE,:);
            %app.y_axis = app.FT_file.trial{1,2}(10,:);

            app.ecog_plot = plot(app.UIAxes,app.time,app.data);
            %disp('new plot generated');

            rownum = 3*app.TRIAL - 2;
            xline(app.UIAxes,app.timings_file(rownum,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum,3),'--r');
            xline(app.UIAxes,app.timings_file(rownum+1,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum+1,3),'--r');
            xline(app.UIAxes,app.timings_file(rownum+2,2),'--r');
            xline(app.UIAxes,app.timings_file(rownum+2,3),'--r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UpdateGraphPanel = uipanel(app.UIFigure);
            app.UpdateGraphPanel.Title = 'Update Graph';
            app.UpdateGraphButton = uibutton(app.UpdateGraphPanel, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            app.SubjectEditFieldLabel = uilabel(app.UpdateGraphPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.UpdateGraphPanel, 'text');
            app.TrialEditFieldLabel = uilabel(app.UpdateGraphPanel);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.UpdateGraphPanel, 'numeric');
            app.ElectrodeEditFieldLabel = uilabel(app.UpdateGraphPanel);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.UpdateGraphPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecog_graph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end